classdef Live_Estimate_Tool < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        DocumentationTab               matlab.ui.container.Tab
        TextArea                       matlab.ui.control.TextArea
        CalculationsTab                matlab.ui.container.Tab
        ImportDataButton               matlab.ui.control.Button
        PlotTempCButton                matlab.ui.control.Button
        PlotPressureMPaButton          matlab.ui.control.Button
        Calculation                    matlab.ui.control.Button
        ExportCalculationsButton       matlab.ui.control.Button
        cleaning                       matlab.ui.control.Button
        Operation_Pressure             matlab.ui.control.UIAxes
        Operation_Temperature          matlab.ui.control.UIAxes
        PressureandTemperatureAnalysisTab  matlab.ui.container.Tab
        cleaning_2                     matlab.ui.control.Button
        PlotChangeInPressureMPaButton  matlab.ui.control.Button
        PlotChangeInTempCButton        matlab.ui.control.Button
        Change_In_Pressure             matlab.ui.control.UIAxes
        Change_in_Temp                 matlab.ui.control.UIAxes
        StressAnalysisTab              matlab.ui.container.Tab
        cleaning_3                     matlab.ui.control.Button
        PlotTotalStressMPaButton       matlab.ui.control.Button
        PlotTotalStressDueToTempMPaButton  matlab.ui.control.Button
        PlotTotalStressDueToPressureMPaButton  matlab.ui.control.Button
        STRESS_CALCULATION             matlab.ui.control.UIAxes
        CreepAnalysisTab               matlab.ui.container.Tab
        cleaning_all                   matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        Accumulated_Creep              matlab.ui.control.Button
        Sustained_stress               matlab.ui.control.Button
        Total_stress                   matlab.ui.control.Button
        Creep_Analysis                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
           Hours
           Pressure
           Inner_Temp
           Outer_Temp
           Temp

           EM      % Elastic Modulus
           SCF     %Stress Concentration Factor αm 
           SCFT    %Stress Concentration Factor αm 
           CLTE    %Coefficient of Linear Thermal Expansion	βLt	K-1
           PR      %Poissons Ratio	 ν		0.3
           LMPC    %LMP constant C	C		31.4
           LMPCa1  %LMP constant a1	a1		34154
		   LMPCa2  %LMP constant a1	a2		3494	
           LMPCa3  %LMP constant a1	a3		2574


            OUTT   %Outer Temp	To	oC	350	Assumed outer wall temperature….check
            IPDC   %%Internal Pipe Diameter	dms	mm	1000	Cylinder
            WLT    %Wall Thickness	ems	mm	25	
            ASTP   %Assumed Starting Temperature 	To	oC	350	Assumed Starting Temperature
            ASP    %Assumed Starting Pressure	Po	MPa	5	Assumed Starting Pressure 
            MPSO   %Minimum period of sustainted operation	Tsmin	Hrs	2
            MSFP   %Maximum Fluctuation of sustainted operation	σmaxFlu	MPa	30	


            Delt_P            % Change in Pressure MPa
            Delt_T            % Change in Wall Temp.

            ISDP              % Incremental Stress Due To Pressure
            TSDP              % Total Stress Due To Pressure

            ISDT              % Incremental Stress Due To Temperature
            TSDT              % Total Stress Due To Temperature


            TS                %  Stress Matrix , 1 for temp, 2 for pressure , 3 for total 
            TS_P              %  Stress cleaned data
            SDA               %  Stress Data Analysis 
            Cells             %  This for sustained regions 


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
           load Raw_Data.txt
           load Mat_Data_Input.txt
           load Op_Constants_Input.txt
%%         
            app.Hours= Raw_Data(:,1) ;
            app.Pressure= Raw_Data(:,2) ;
            app.Inner_Temp= Raw_Data(:,3) ; 
            app.Outer_Temp= Raw_Data(:,4) ; 
            app.Temp= - Raw_Data(:,4) + Raw_Data(:,3) ; % change through wall
%%
            app.EM     = Mat_Data_Input(1,1) ; % Elastic Modulus
            app.SCF    = Mat_Data_Input(2,1) ; %Stress Concentration Factor αm 
            app.SCFT   = Mat_Data_Input(3,1) ; %Stress Concentration Factor αm 
            app.CLTE   = Mat_Data_Input(4,1) ; %Coefficient of Linear Thermal Expansion	βLt	K-1
            app.PR     = Mat_Data_Input(5,1) ; %Poissons Ratio	 ν		0.3
            app.LMPC   = Mat_Data_Input(6,1) ; %LMP constant C	C		31.4
            app.LMPCa1 = Mat_Data_Input(7,1) ; %LMP constant a1	a1		34154
			app.LMPCa2 = Mat_Data_Input(8,1) ; %LMP constant a1	a2		3494	
        	app.LMPCa3 = Mat_Data_Input(9,1) ; %LMP constant a1	a3		2574
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%
            app.OUTT = Op_Constants_Input(1,1) ; %Outer Temp	To	oC	350	Assumed outer wall temperature….check
            app.IPDC = Op_Constants_Input(2,1) ; %Internal Pipe Diameter	dms	mm	254	Cylinder
            app.WLT  = Op_Constants_Input(4,1) ; %Wall Thickness	ems	mm	25	
            app.ASTP = Op_Constants_Input(5,1) ; %Assumed Starting Temperature 	To	oC	350	Assumed Starting Temperature
            app.ASP  = Op_Constants_Input(6,1) ; %Assumed Starting Pressure	Po	MPa	0.1	Assumed Starting Pressure 
            app.MPSO = Op_Constants_Input(7,1) ; %Minimum period of sustainted operation	Tsmin	Hrs	2
            app.MSFP = Op_Constants_Input(8,1) ; %Maximum Fluctuation of sustainted operation	σmaxFlu	MPa	30
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%%


        end

        % Button pushed function: PlotTempCButton
        function PlotTempCButtonPushed(app, event)
             plot(app.Operation_Temperature,app.Hours,app.Inner_Temp,'r');
             hold(app.Operation_Temperature ,'on')
             plot(app.Operation_Temperature,app.Hours,app.Outer_Temp,'--r');
             legend(app.Operation_Temperature  ,' Inner Steam Temp (oC)',' Outer Wall Temp (oC)','Location','southeast');
        end

        % Button pushed function: PlotPressureMPaButton
        function PlotPressureMPaButtonPushed(app, event)
            plot(app.Operation_Pressure,app.Hours,app.Pressure,'b');
        end

        % Button pushed function: PlotChangeInPressureMPaButton
        function PlotChangeInPressureMPaButtonPushed(app, event)
            plot(app.Change_In_Pressure,app.Hours,app.Delt_P,'b'); 
        end

        % Button pushed function: PlotChangeInTempCButton
        function PlotChangeInTempCButtonPushed(app, event)
            plot(app.Change_in_Temp,app.Hours,app.Temp,'r');
        end

        % Button pushed function: PlotTotalStressDueToTempMPaButton
        function PlotTotalStressDueToTempMPaButtonPushed(app, event)
             hold(app.STRESS_CALCULATION ,'on')
             plot(app.STRESS_CALCULATION ,app.Hours,app.TS(:,1),'r');
             legend(app.STRESS_CALCULATION  ,' Stress Due to Pressure (MPa)',' Stress Due to Temperature (MPa)','Location','southeast');
        end

        % Button pushed function: PlotTotalStressDueToPressureMPaButton
        function PlotTotalStressDueToPressureMPaButtonPushed(app, event)
           plot(app.STRESS_CALCULATION ,app.Hours,app.TS(:,2),'b'); 
           legend(app.STRESS_CALCULATION ,'Stress Due to Pressure (MPa) ','Location','southeast');
           hold(app.STRESS_CALCULATION ,'on')
        end

        % Button pushed function: PlotTotalStressMPaButton
        function PlotTotalStressMPaButtonPushed(app, event)
          hold(app.STRESS_CALCULATION ,'on')
          plot(app.STRESS_CALCULATION ,app.Hours,app.TS(:,3),	"Color",	"#EDB120"); 
          legend(app.STRESS_CALCULATION ,' Stress Due to Pressure (MPa)',' Stress Due to Temperature (MPa)','Total Stress (MPa)','Location','southeast');
          hold(app.STRESS_CALCULATION ,'off')

%           hold(app.STRESS_Analysis,'on')
%           plot(app.STRESS_Analysis ,app.Hours,app.TS(:,3),	"Color",	"#EDB120"); 
%           legend(app.STRESS_Analysis, 'Total Stress (MPa)','Location','southwest');
%           hold(app.STRESS_Analysis,'on')


        end

        % Button pushed function: ExportCalculationsButton
        function ExportCalculationsButtonPushed(app, event)
               
            assignin ( 'base', 'Change_in_Temp', app.Delt_T) 
            assignin ( 'base', 'Change_in_Pressure', app.Delt_P) 
            assignin ( 'base', 'stress_Data', app.TS) 
            assignin ( 'base', 'Total_stress_cleaned', app.TS_P)   
            assignin ( 'base', 'Sustained_stress_Regions', app.Cells)
                        
            
        end

        % Callback function
        function cleaned_stressPushed(app, event)
          hold(app.STRESS_Analysis,'on')
          plot(app.STRESS_Analysis,app.TS_P(:,1),app.TS_P(:,2),'k'); 
          
          legend(app.STRESS_Analysis ,'Total Stress (MPa)',' Peak and Trough cleaned Total Stress (MPa)','Location','southeast');
          hold(app.STRESS_Analysis,'off')

        end

        % Button pushed function: Total_stress
        function Total_stressButtonPushed(app, event)
        
             
            yyaxis(app.Creep_Analysis,'left')
            app.Creep_Analysis.YColor='#D95319'
            plot(app.Creep_Analysis ,app.Hours,app.TS(:,3),"Color",	"#EDB120"); 
           
            legend(app.Creep_Analysis ,'Total Stress (MPa)','Location','southeast');
            hold(app.Creep_Analysis,'off')

        end

        % Button pushed function: Sustained_stress
        function Sustained_stressButtonPushed(app, event)
          hold(app.Creep_Analysis,'on')
                      yyaxis(app.Creep_Analysis,'left')

            plot(app.Creep_Analysis ,app.Cells(:,6),app.Cells(:,7),'X','LineWidth',3,"Color",	"#D95319"); 

          legend(app.Creep_Analysis ,'Total Stress (MPa)','Sustained Stress (MPa)','Location','southeast');
            hold(app.Creep_Analysis,'off')
        end

        % Button pushed function: cleaning
        function cleaningPushed(app, event)
            delete(             plot(app.Operation_Temperature,app.Hours,app.Temp,'r'));
            delete(            plot(app.Operation_Pressure,app.Hours,app.Pressure,'b')) ;

        end

        % Button pushed function: cleaning_2
        function cleaning_2ButtonPushed(app, event)
            delete (plot(app.Change_In_Pressure,app.Hours,app.Delt_P,'b') ) ;
            delete (plot(app.Change_in_Temp,app.Hours,app.Delt_T,'r')) ;

        end

        % Button pushed function: cleaning_3
        function cleaning_3ButtonPushed(app, event)
            delete(  plot(app.STRESS_CALCULATION ,app.Hours,app.TS(:,3),"Color",	"#EDB120")) ;
            delete(  plot(app.STRESS_CALCULATION ,app.Hours,app.TS(:,2),'b')) ;
            delete(plot(app.STRESS_CALCULATION ,app.Hours,app.TS(:,1),'r')) ;
%             delete( plot(app.STRESS_Analysis,app.TS_P(:,1),app.TS_P(:,2),'k')) ;
%             delete(  plot(app.STRESS_Analysis ,app.Hours,app.TS(:,3),"Color",	"#EDB120")) ;

        end

        % Callback function
        function cleaning_4ButtonPushed(app, event)
            delete( plot(app.Creep_Analysis ,app.Cells(:,5),app.Cells(:,6),'X','LineWidth',3));
            delete( plot(app.Creep_Analysis ,app.Hours,app.TS(:,3),"Color",	"#EDB120") );
            delete( plot(app.Creep_Analysis,app.Cells(:,5),app.Cells(:,12),'-o'));

        end

        % Button pushed function: Accumulated_Creep
        function Accumulated_CreepButtonPushed(app, event)
              hold(app.Creep_Analysis,'on')
              yyaxis(app.Creep_Analysis,'right')
              app.Creep_Analysis.YColor='#7E2F8E'
              plot(app.Creep_Analysis,app.Cells(:,6),app.Cells(:,19),'-o',"Color","#7E2F8E");
              legend(app.Creep_Analysis ,'Total Stress (MPa)','Sustained Stress (MPa)','Accumulated Creep Damage Fraction ','Location','southeast');
              hold(app.Creep_Analysis,'off')
        end

        % Button pushed function: cleaning_all
        function cleaning_allButtonPushed2(app, event)
         yyaxis(app.Creep_Analysis,'right')

         delete(plot(app.Creep_Analysis,app.Cells(:,6),app.Cells(:,19),'-o')) ;
         yyaxis(app.Creep_Analysis,'left')

          delete(plot(app.Creep_Analysis ,app.Cells(:,6),app.Cells(:,7),'X','LineWidth',3)) ;
          delete(plot(app.Creep_Analysis ,app.Hours,app.TS(:,3),"Color",	"#EDB120")) ;


        end

        % Button pushed function: Calculation
        function CalculationButtonPushed(app, event)
            %%

            app.Delt_P = zeros (size(app.Hours,1),1) ;  % Change in Pressure MPa
            app.Delt_P(1,1)=app.Pressure(1,1) -  app.ASP ;
            for i =2: size(app.Hours,1)
                app.Delt_P(i,1) =  app.Pressure(i,1) -  app.Pressure(i-1,1);
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%

            app.Delt_T = zeros (size(app.Hours,1),1) ;  % Change in Wall Temp.
            app.Delt_T(1,1) =  app.Temp(1,1) -  app.Temp(1,1) ;
            for i =2 : size(app.Hours,1)
                app.Delt_T(i,1) =  app.Temp(i,1) -  app.Temp(i-1,1) ;
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

%%

            app.ISDP = zeros (size(app.Hours,1),1) ;  % Incremental Stress Due To Pressure


            for i =1 : size(app.Hours,1)
                app.ISDP(i,1) =  app.SCF *app.IPDC* app.Delt_P(i,1)/(2*app.WLT)  ;
            end

            app.TSDP = zeros (size(app.Hours,1),1) ;  % Total Stress Due To Pressure
            
            app.TSDP(1,1)=app.ISDP(1,1);
            for i = 2 : size(app.Hours,1)
                app.TSDP(i,1) =  app.ISDP(i,1) +  app.TSDP(i-1,1);
            end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%%
           app.ISDT = zeros (size(app.Hours,1),1) ;  % Incremental Stress Due To Temp


            for i =1 : size(app.Hours,1)
                app.ISDT(i,1) =  app.SCFT *app.CLTE* app.EM* app.Delt_T(i,1)/(1- app.PR)  ;
            end

            app.TSDT = zeros (size(app.Hours,1),1) ;  % Total Stress Due To Pressure
            app.TSDT(1,1) =app.ISDT(1,1) ;

            for i =2 : size(app.Hours,1)
                app.TSDT(i,1) =  app.ISDT(i,1) +app.TSDT(i-1,1) ;
            end

            app.TS= zeros (size(app.Hours,1),5); % 1.. temp , 2.. pressure, 3 .. total, 4.... difference in total stress, ....5 Ratio


            app.TS(:,1)= app.TSDT(:,1) ;
            app.TS(:,2)= app.TSDP(:,1) ;

            for i =1 : size(app.Hours,1)
                app.TS(i,3) =  app.TS(i,1)  + app.TS(i,2)  ;
            end
%% Stress Data Analysis
            j =1 ; 
            app.TS(1,4) =  1 ;
            app.TS(1,5) =  -1 ;
            app.TS(size(app.Hours,1),5) =  0 ;
            for i =2 : size(app.Hours,1)
                app.TS(i,4) =  app.TS(i,3)  - app.TS(i-1,3)  ;
            end 
            for i =2 : size(app.Hours,1)-1 
                 app.TS(i,5) = app.TS(i+1,4)/app.TS(i,4) ; 
                 if  app.TS(i+1,4) ~= 0 && app.TS(i,4)  == 0 
                   app.TS(i,5) = -1 ; 
                 end 
                 if  app.TS(i+1,4) == 0 && app.TS(i,4)  == 0 
                   app.TS(i,5) = -1 ; 
                 end 
               
            end 
            j=0 ;
            for i = 1 : size(app.Hours,1) 
                if  app.TS(i,5)  < 0  
                  j=j+1;
                end
           end


           app.TS_P = zeros (j,2);
                  j=0 ;
           for i = 1 : size(app.Hours,1) 
                if  app.TS(i,5)  < 0  
                  j=j+1;
                  app.TS_P (j,1)  = app.Hours(i,1)  ;
                  app.TS_P (j,2)  = app.TS(i,3)  ;
                  
                end
           end
%% Stress Creep Analysis                 


Stress = app.TS(1,3) ;
StressMax = Stress ;
StressMin = Stress ;
StressMean = Stress ;
StressMaxSave = Stress ;
StressMinSave = Stress ;
Stressforaverage = Stress ;
Stress2 = Stress ;
Stress1 = Stress ;

Tempx= app.Inner_Temp(1,1) ;
TempMax = Tempx ;
TempMin = Tempx ;
TempMean = Tempx ;
TempRange = 0 ;
Temp2 = Tempx ;
Tempforaverage=Tempx;


Time = app.Hours(1,1) ;
StressThreshold = app.MSFP;
PeroidMin = app.MPSO;
n = 1 ;
m = 0 ;
Duration = 0 ;


Time1 = app.Hours(1,1) ;
starttime = app.Hours(1,1) ;
endtime =app.Hours(1,1) ;


for i = 1 : size(app.Hours,1)
    
    Stress2 = app.TS(i,3);
    Time2 = app.Hours(i,1) ;
    Temp2 = app.Inner_Temp(i,1) ;

    if (Stress2 >= StressMax) 
    StressMaxSave = StressMax ;
    StressMax = Stress2 ;
    end

    if (Stress2 <= StressMin)
    StressMinSave = StressMin ;
    StressMin = Stress2 ;
    end
    
    Difference = StressMax - StressMin ;
    
    if (abs(Difference) <= StressThreshold) 
     n = n + 1;
     endtime = Time2;
     Duration = endtime - starttime;
     Stressforaverage = Stressforaverage + Stress2;
     Tempforaverage = Tempforaverage + Temp2 ;
    
    if (Temp2 >= TempMax) 
    TempMax = Temp2 ;
    end 
    
    if (Temp2 <= TempMin) 
    TempMin = Temp2;
    end
    
    TempRange = TempMax - TempMin;


    else
     if (Duration >= PeroidMin) 
        m = m + 1;
        StressMean = Stressforaverage / n ;
        TempMean = Tempforaverage / n ;
        app.Cells( m, 1) = m ;
        app.Cells( m, 2) = Duration;
        app.Cells( m, 3) = Duration / 2;
        app.Cells( m, 4) = starttime;
        app.Cells( m, 5)= endtime;
        app.Cells( m, 6)= (starttime + endtime) / 2;
        app.Cells( m, 7)= StressMean;
        app.Cells( m, 8) = StressMaxSave;
        app.Cells( m, 9) = StressMinSave;
        app.Cells( m, 10) = (StressMaxSave - StressMinSave) / 2;
        app.Cells( m, 11) = TempMean;
        app.Cells( m, 12) = TempMax;
        app.Cells( m, 13) = TempMin;
        app.Cells( m, 14) = TempMax - TempMin;


        n = 0 ;
        starttime = Time2;
        endtime = Time2;
        Time1 = Time2;
        Stress1 = Stress2;
        Duration = 0;
        Stressforaverage = 0;
        StressMin = Stress2;
        StressMax = Stress2;
        StressMean = Stress2;
        StressMaxSave = Stress2;
        StressMinSave = Stress2;
        Tempforaverage = 0 ;
        TempMax = Temp2;
        TempMin = Temp2;
        TempMean = Temp2   ;

     else
        n = 0 ;
        starttime = Time2;
        endtime = Time2;
        Time1 = Time2;
        Stress1 = Stress2;
        Duration = 0;
        Stressforaverage = 0;
        StressMin = Stress2;
        StressMax = Stress2;
        StressMean = Stress2;
        StressMaxSave = Stress2;
        StressMinSave = Stress2;
        Tempforaverage = 0 ;
        TempMax = Temp2;
        TempMin = Temp2;
        TempMean = Temp2   ;
     end
    end
end  

app.Cells( :, 15)=log10( abs(app.Cells( : , 7) )  ) ;
for i=1 : size(app.Cells,1) 
app.Cells( i, 16)=-app.LMPC + ( (app.LMPCa1 + app.LMPCa2*app.Cells( i, 15) ) - app.LMPCa3*(app.Cells( i, 15)^2) )/(app.Cells( i, 11)  +273) ;
end
for i=1 : size(app.Cells,1) 
app.Cells( i, 17)=10^app.Cells( i, 16) ;
end
for i=1 : size(app.Cells,1) 
app.Cells( i, 18)=app.Cells( i, 2)/app.Cells( i, 17) ;
end
app.Cells( :, 19)=0 ;

app.Cells( 1, 19) = app.Cells( 1, 18) ;
for i=2 : size(app.Cells,1) 
    app.Cells( i, 19) =app.Cells( i, 18)+app.Cells( i-1,19) ;
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DocumentationTab = uitab(app.TabGroup);
            app.DocumentationTab.Title = 'Documentation';
            app.TextArea = uitextarea(app.DocumentationTab);
            app.TextArea.Value = {'This MATLAB code has been created to serve as Life assessment Tool for power Plant Operation. The Main calculation are for the stress due temperature and stress due to pressure. The main failure calculations are creep.'; 'The first page for important notes and documentations. The second showing the operation temperature and pressure. The third and forth are for detailed stress calculations and the creep.'; ''; 'The main equations and the methodology is based on the publication :'; ''; 'Harrison, NM, Adams, A, O’Donoghue, PE, & Leen, SB. "A Combined Creep and Fatigue Damage Estimation Tool for Power-Plant Monitoring." Proceedings of the ASME 2016 Pressure Vessels and Piping Conference. Volume 6B: Materials and Fabrication. Vancouver, British Columbia, Canada. July 17–21, 2016. V06BT06A043. ASME. https://doi.org/10.1115/PVP2016-63513'; ''; 'This App is presented in Ch5 for Msc thesis "Multiscale Numerical Study of Tempered Martensitic Grade 91 (P) Steel at High Shear Strain"'; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''; ''};
            app.CalculationsTab = uitab(app.TabGroup);
            app.CalculationsTab.Title = 'Calculations ';
            app.Operation_Temperature = uiaxes(app.CalculationsTab);
            title(app.Operation_Temperature, 'Operation Temperature')
            xlabel(app.Operation_Temperature, 'Time (hours)')
            ylabel(app.Operation_Temperature, 'Temp. C')
            zlabel(app.Operation_Temperature, 'Z')
            app.Operation_Pressure = uiaxes(app.CalculationsTab);
            title(app.Operation_Pressure, 'Operation Pressure')
            xlabel(app.Operation_Pressure, 'Time (hours)')
            ylabel(app.Operation_Pressure, 'Presure MPa')
            zlabel(app.Operation_Pressure, 'Z')
            app.cleaning = uibutton(app.CalculationsTab, 'push');
            app.cleaning.ButtonPushedFcn = createCallbackFcn(app, @cleaningPushed, true);
            app.cleaning.Icon = fullfile(pathToMLAPP, 'chrome_kag4tvlqMc.png');
            app.cleaning.Text = '';
            app.ExportCalculationsButton = uibutton(app.CalculationsTab, 'push');
            app.ExportCalculationsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportCalculationsButtonPushed, true);
            app.ExportCalculationsButton.Icon = fullfile(pathToMLAPP, 'data-export-icon-0.png');
            app.ExportCalculationsButton.Text = '';
            app.Calculation = uibutton(app.CalculationsTab, 'push');
            app.Calculation.ButtonPushedFcn = createCallbackFcn(app, @CalculationButtonPushed, true);
            app.Calculation.Icon = fullfile(pathToMLAPP, '75775.png');
            app.Calculation.Text = '';
            app.PlotPressureMPaButton = uibutton(app.CalculationsTab, 'push');
            app.PlotPressureMPaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPressureMPaButtonPushed, true);
            app.PlotPressureMPaButton.Icon = fullfile(pathToMLAPP, 'temp-icon-300x300.png');
            app.PlotPressureMPaButton.Text = '';
            app.PlotTempCButton = uibutton(app.CalculationsTab, 'push');
            app.PlotTempCButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTempCButtonPushed, true);
            app.PlotTempCButton.Icon = fullfile(pathToMLAPP, '2241260.png');
            app.PlotTempCButton.Text = '';
            app.ImportDataButton = uibutton(app.CalculationsTab, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Icon = fullfile(pathToMLAPP, '2018305-200.png');
            app.ImportDataButton.Text = '';
            app.PressureandTemperatureAnalysisTab = uitab(app.TabGroup);
            app.PressureandTemperatureAnalysisTab.Title = 'Pressure and Temperature Analysis';
            app.Change_in_Temp = uiaxes(app.PressureandTemperatureAnalysisTab);
            title(app.Change_in_Temp, 'Change in Temp (C)')
            xlabel(app.Change_in_Temp, 'Time (hours)')
            ylabel(app.Change_in_Temp, 'Temp. C')
            zlabel(app.Change_in_Temp, 'Z')
            app.Change_In_Pressure = uiaxes(app.PressureandTemperatureAnalysisTab);
            title(app.Change_In_Pressure, 'Change In Pressure MPa')
            xlabel(app.Change_In_Pressure, 'Time (hours)')
            ylabel(app.Change_In_Pressure, 'Presure MPa')
            zlabel(app.Change_In_Pressure, 'Z')
            app.PlotChangeInTempCButton = uibutton(app.PressureandTemperatureAnalysisTab, 'push');
            app.PlotChangeInTempCButton.ButtonPushedFcn = createCallbackFcn(app, @PlotChangeInTempCButtonPushed, true);
            app.PlotChangeInTempCButton.Icon = fullfile(pathToMLAPP, 'chtemp.png');
            app.PlotChangeInTempCButton.Text = '';
            app.PlotChangeInPressureMPaButton = uibutton(app.PressureandTemperatureAnalysisTab, 'push');
            app.PlotChangeInPressureMPaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotChangeInPressureMPaButtonPushed, true);
            app.PlotChangeInPressureMPaButton.Icon = fullfile(pathToMLAPP, 'chper.png');
            app.PlotChangeInPressureMPaButton.Text = '';
            app.cleaning_2 = uibutton(app.PressureandTemperatureAnalysisTab, 'push');
            app.cleaning_2.ButtonPushedFcn = createCallbackFcn(app, @cleaning_2ButtonPushed, true);
            app.cleaning_2.Icon = fullfile(pathToMLAPP, 'chrome_kag4tvlqMc.png');
            app.cleaning_2.Text = '';
            app.StressAnalysisTab = uitab(app.TabGroup);
            app.StressAnalysisTab.Title = 'Stress Analysis';
            app.STRESS_CALCULATION = uiaxes(app.StressAnalysisTab);
            title(app.STRESS_CALCULATION, 'Stress Calculation MPa')
            xlabel(app.STRESS_CALCULATION, 'Time (hours)')
            ylabel(app.STRESS_CALCULATION, 'Stress (MPa)')
            zlabel(app.STRESS_CALCULATION, 'Z')
            app.PlotTotalStressDueToPressureMPaButton = uibutton(app.StressAnalysisTab, 'push');
            app.PlotTotalStressDueToPressureMPaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTotalStressDueToPressureMPaButtonPushed, true);
            app.PlotTotalStressDueToPressureMPaButton.Icon = fullfile(pathToMLAPP, 'sp2.png');
            app.PlotTotalStressDueToPressureMPaButton.Text = '';
            app.PlotTotalStressDueToTempMPaButton = uibutton(app.StressAnalysisTab, 'push');
            app.PlotTotalStressDueToTempMPaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTotalStressDueToTempMPaButtonPushed, true);
            app.PlotTotalStressDueToTempMPaButton.Icon = fullfile(pathToMLAPP, 'st2.png');
            app.PlotTotalStressDueToTempMPaButton.Text = '';
            app.PlotTotalStressMPaButton = uibutton(app.StressAnalysisTab, 'push');
            app.PlotTotalStressMPaButton.ButtonPushedFcn = createCallbackFcn(app, @PlotTotalStressMPaButtonPushed, true);
            app.PlotTotalStressMPaButton.Icon = fullfile(pathToMLAPP, 'sg9.png');
            app.PlotTotalStressMPaButton.Text = '';
            app.cleaning_3 = uibutton(app.StressAnalysisTab, 'push');
            app.cleaning_3.ButtonPushedFcn = createCallbackFcn(app, @cleaning_3ButtonPushed, true);
            app.cleaning_3.Icon = fullfile(pathToMLAPP, 'chrome_kag4tvlqMc.png');
            app.cleaning_3.Text = '';
            app.CreepAnalysisTab = uitab(app.TabGroup);
            app.CreepAnalysisTab.Title = 'Creep Analysis';
            app.Creep_Analysis = uiaxes(app.CreepAnalysisTab);
            title(app.Creep_Analysis, 'Total Stress, Sustained stress & Creep Analysis ')
            xlabel(app.Creep_Analysis, 'Time (hours)')
            ylabel(app.Creep_Analysis, 'Stress (MPa)')
            zlabel(app.Creep_Analysis, 'Z')
            app.Creep_Analysis.YLim = [-500 600];
            colormap(app.Creep_Analysis, 'autumn')
            app.Total_stress = uibutton(app.CreepAnalysisTab, 'push');
            app.Total_stress.ButtonPushedFcn = createCallbackFcn(app, @Total_stressButtonPushed, true);
            app.Total_stress.Icon = fullfile(pathToMLAPP, 'sg9.png');
            app.Total_stress.Text = '';
            app.Sustained_stress = uibutton(app.CreepAnalysisTab, 'push');
            app.Sustained_stress.ButtonPushedFcn = createCallbackFcn(app, @Sustained_stressButtonPushed, true);
            app.Sustained_stress.Icon = fullfile(pathToMLAPP, 'Acrobat_Df2IDXzbVm.png');
            app.Sustained_stress.Text = '';
            app.Accumulated_Creep = uibutton(app.CreepAnalysisTab, 'push');
            app.Accumulated_Creep.ButtonPushedFcn = createCallbackFcn(app, @Accumulated_CreepButtonPushed, true);
            app.Accumulated_Creep.Text = {'Accumulated'; ' Creep'};
            app.Image = uiimage(app.CreepAnalysisTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'creep_h.png');
            app.cleaning_all = uibutton(app.CreepAnalysisTab, 'push');
            app.cleaning_all.ButtonPushedFcn = createCallbackFcn(app, @cleaning_allButtonPushed2, true);
            app.cleaning_all.Icon = fullfile(pathToMLAPP, 'chrome_kag4tvlqMc.png');
            app.cleaning_all.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Live_Estimate_Tool

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end